classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        modeButtonGroup              matlab.ui.container.ButtonGroup
        bilateralButton              matlab.ui.control.RadioButton
        unilateral_leftButton        matlab.ui.control.RadioButton
        unilateral_rightButton       matlab.ui.control.RadioButton
        strategyButtonGroup          matlab.ui.container.ButtonGroup
        ACEButton                    matlab.ui.control.RadioButton
        TLEButton                    matlab.ui.control.RadioButton
        bACEButton                   matlab.ui.control.RadioButton
        bTLEButton                   matlab.ui.control.RadioButton
        RUNButton                    matlab.ui.control.Button
        ParametersPanel              matlab.ui.container.Panel
        LeftPanel                    matlab.ui.container.Panel
        SensitivityEditFieldLabel    matlab.ui.control.Label
        LeftSensitivityEditField     matlab.ui.control.NumericEditField
        VolumnEditFieldLabel         matlab.ui.control.Label
        LeftVolumnEditField          matlab.ui.control.NumericEditField
        RightPanel                   matlab.ui.container.Panel
        SensitivityEditField_2Label  matlab.ui.control.Label
        RightSensitivityEditField    matlab.ui.control.NumericEditField
        VolumnEditField_2Label       matlab.ui.control.Label
        RightVolumnEditField         matlab.ui.control.NumericEditField
        SubjectInfoPanel             matlab.ui.container.Panel
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        LeftMapEditFieldLabel        matlab.ui.control.Label
        LeftMapEditField             matlab.ui.control.EditField
        RightMapEditFieldLabel       matlab.ui.control.Label
        RightMapEditField            matlab.ui.control.EditField
        fltpHzButtonGroup            matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        Button_2                     matlab.ui.control.RadioButton
        Button_3                     matlab.ui.control.RadioButton
        otherButton                  matlab.ui.control.RadioButton
        fltpHzEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        ccimobile % the CCi-mobile object
%         lmap; % left map
%         rmap; % right map

        
    end
    
    methods (Access = public)
        
        function enableButtons(app,OnOff) % OnOff: 'on' or 'off'
            set(app.ACEButton,'Enable',OnOff);
            set(app.TLEButton,'Enable',OnOff);
            set(app.bACEButton,'Enable',OnOff);
            set(app.bTLEButton,'Enable',OnOff);
            set(app.bilateralButton,'Enable',OnOff);
            set(app.unilateral_leftButton,'Enable',OnOff);
            set(app.unilateral_rightButton,'Enable',OnOff);
            set(app.fltpHzEditField,'Enable',OnOff);
%             set(app.LeftSensitivityEditField,'Enable',OnOff);
%             set(app.LeftVolumnEditField,'Enable',OnOff);
%             set(app.RightSensitivityEditField,'Enable',OnOff);
%             set(app.RightVolumnEditField,'Enable',OnOff);
            set(app.NameEditField,'Enable',OnOff);
            set(app.LeftMapEditField,'Enable',OnOff);
            set(app.RightMapEditField,'Enable',OnOff);
            
        end
        
        function [lmap,rmap] = loadMap(app)
            lname = ['.\MAPs\',app.LeftMapEditField.Value,'.txt'];
                rname = ['.\MAPs\',app.RightMapEditField.Value,'.txt'];
                switch app.modeButtonGroup.SelectedObject.Text
                    case 'bilateral'
                        if exist(lname,'file') && exist(rname,'file')
                            lmap = CCiMobileMap('left',lname,app.ccimobile.FS);
                            rmap = CCiMobileMap('right',rname,app.ccimobile.FS);
                        else
                            uiwait(errordlg('left or right map file does not exist','Error'));
                            set(app.RUNButton,'Text','RUN'); % change STOP into RUN
                            set(app.RUNButton,'BackgroundColor',[0 1 0]); % green for STOP
                            app.enableButtons('on');
                            return;
                        
                        end
                    case 'unilateral_left'
                        if exist(lname,'file')
                            %filename = ['.\MAPs\',app.LeftMapEditField.Value,'.txt'];
                            lmap = CCiMobileMap('left',lname,app.ccimobile.FS);
                            rmap = [];
                        else
                            uiwait(errordlg(['left map file does not exist'],'Error'))
                        end
                    case 'unilateral_right'
                        %filename = ['.\MAPs\',app.RightMapEditField.Value,'.txt'];
                        rmap = CCiMobileMap('right',rname,app.ccimobile.FS);
                        lmap = [];
                    otherwise
                        uiwait(errordlg(['Unkown mode, should be bilateral,...' ...
                            'unilateral_left, or unilateral_right'],'Error'))
                end
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('CCiMobileTools')
            addpath('.\CCiMobileTools\Strategies')
            addpath('.\CCiMobileTools\CommonFunctions')
            app.ccimobile = CCiMobile();
            app.fltpHzButtonGroup.Visible = 'Off'; % not show the fltp on initial interface
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            if strcmp(app.RUNButton.Text,'RUN')
                % fresh interface
                set(app.RUNButton,'Text','STOP'); % change RUN into STOP
                set(app.RUNButton,'BackgroundColor',[1 0 0]); % red for STOP
                app.enableButtons('off');
                
                app.ccimobile.set_property('mode',app.modeButtonGroup.SelectedObject.Text);
                app.ccimobile.set_property('left_sensitivity_gain',app.LeftSensitivityEditField.Value);
                app.ccimobile.set_property('right_sensitivity_gain',app.RightSensitivityEditField.Value);
                app.ccimobile.set_property('left_volume',app.LeftVolumnEditField.Value);
                app.ccimobile.set_property('right_volume',app.RightVolumnEditField.Value);

                % load map
                [lmap,rmap] = app.loadMap();
                
                % create strategy object
                sObj = feval(str2func(app.strategyButtonGroup.SelectedObject.Text));
                sObj.initialize(lmap,rmap,app.ccimobile.FRAMESIZE,app.ccimobile.FS);
                if isa(sObj,'TLE') || isa(sObj,'bTLE')
                    if strcmp(app.fltpHzButtonGroup.SelectedObject.Text,'other')
                        sObj.fltp = app.fltpHzEditField.Value;
                    else
                        sObj.fltp = str2double(app.fltpHzButtonGroup.SelectedObject.Text);
                    end
                end
                fprintf(1,'Running %s\n',class(sObj));
                if isa(sObj,'TLE') || isa(sObj,'bTLE')
                    fprintf(1,' fltp %d\n',sObj.fltp);
                end
                app.ccimobile.process(sObj,'microphone','rfcoil');
            else
                % fresh interface
                set(app.RUNButton,'Text','RUN'); % change STOP into RUN
                set(app.RUNButton,'BackgroundColor',[0 1 0]); % green for STOP
                app.enableButtons('on');
                
                % stop ccimobile
                app.ccimobile.stop();
                
                
            end
        end

        % Selection changed function: modeButtonGroup
        function modeButtonGroupSelectionChanged(app, event)
            selectedButton = app.modeButtonGroup.SelectedObject;
        
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            app.LeftMapEditField.Value = [value,'_left'];
            app.RightMapEditField.Value = [value,'_right'];
        end

        % Selection changed function: strategyButtonGroup
        function strategyButtonGroupSelectionChanged(app, event)
            selectedButton = app.strategyButtonGroup.SelectedObject;
            if contains(selectedButton.Text(end-2:end),'ACE') % for ACE and bACE
                app.fltpHzButtonGroup.Visible = 'Off';
            else
                app.fltpHzButtonGroup.Visible = 'On';                
            end
        end

        % Value changed function: LeftSensitivityEditField
        function LeftSensitivityEditFieldValueChanged(app, event)
            value = round(app.LeftSensitivityEditField.Value,1);
            app.ccimobile.set_property('left_sensitivity_gain',value);
        end

        % Value changed function: RightSensitivityEditField
        function RightSensitivityEditFieldValueChanged(app, event)
            value = round(app.RightSensitivityEditField.Value,1);
            app.ccimobile.set_property('right_sensitivity_gain',value);
        end

        % Value changed function: LeftVolumnEditField
        function LeftVolumnEditFieldValueChanged(app, event)
            value = round(app.LeftVolumnEditField.Value,1);
            app.ccimobile.set_property('left_volume',value);
        end

        % Value changed function: RightVolumnEditField
        function RightVolumnEditFieldValueChanged(app, event)
            value = round(app.RightVolumnEditField.Value,1);
            app.ccimobile.set_property('right_volume',value);
        end

        % Value changed function: fltpHzEditField
        function fltpHzEditFieldValueChanged(app, event)
            value = app.fltpHzEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.modeButtonGroup = uibuttongroup(app.UIFigure);
            app.modeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @modeButtonGroupSelectionChanged, true);
            app.modeButtonGroup.Title = 'mode';
            app.bilateralButton = uiradiobutton(app.modeButtonGroup);
            app.bilateralButton.Text = 'bilateral';
            app.bilateralButton.Value = true;
            app.unilateral_leftButton = uiradiobutton(app.modeButtonGroup);
            app.unilateral_leftButton.Text = 'unilateral_left';
            app.unilateral_rightButton = uiradiobutton(app.modeButtonGroup);
            app.unilateral_rightButton.Text = 'unilateral_right';
            app.strategyButtonGroup = uibuttongroup(app.UIFigure);
            app.strategyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @strategyButtonGroupSelectionChanged, true);
            app.strategyButtonGroup.Title = 'strategy';
            app.ACEButton = uiradiobutton(app.strategyButtonGroup);
            app.ACEButton.Text = 'ACE';
            app.ACEButton.Value = true;
            app.TLEButton = uiradiobutton(app.strategyButtonGroup);
            app.TLEButton.Text = 'TLE';
            app.bACEButton = uiradiobutton(app.strategyButtonGroup);
            app.bACEButton.Text = 'bACE';
            app.bTLEButton = uiradiobutton(app.strategyButtonGroup);
            app.bTLEButton.Text = 'bTLE';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.LeftPanel = uipanel(app.ParametersPanel);
            app.LeftPanel.Title = 'Left';
            app.SensitivityEditFieldLabel = uilabel(app.LeftPanel);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.LeftSensitivityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LeftSensitivityEditField.Limits = [-30 30];
            app.LeftSensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @LeftSensitivityEditFieldValueChanged, true);
            app.VolumnEditFieldLabel = uilabel(app.LeftPanel);
            app.VolumnEditFieldLabel.Text = 'Volumn';
            app.LeftVolumnEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LeftVolumnEditField.Limits = [0 10];
            app.LeftVolumnEditField.ValueChangedFcn = createCallbackFcn(app, @LeftVolumnEditFieldValueChanged, true);
            app.LeftVolumnEditField.Value = 10;
            app.RightPanel = uipanel(app.ParametersPanel);
            app.RightPanel.Title = 'Right';
            app.SensitivityEditField_2Label = uilabel(app.RightPanel);
            app.SensitivityEditField_2Label.Text = 'Sensitivity';
            app.RightSensitivityEditField = uieditfield(app.RightPanel, 'numeric');
            app.RightSensitivityEditField.Limits = [-30 30];
            app.RightSensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @RightSensitivityEditFieldValueChanged, true);
            app.VolumnEditField_2Label = uilabel(app.RightPanel);
            app.VolumnEditField_2Label.Text = 'Volumn';
            app.RightVolumnEditField = uieditfield(app.RightPanel, 'numeric');
            app.RightVolumnEditField.Limits = [0 10];
            app.RightVolumnEditField.ValueChangedFcn = createCallbackFcn(app, @RightVolumnEditFieldValueChanged, true);
            app.RightVolumnEditField.Value = 10;
            app.SubjectInfoPanel = uipanel(app.UIFigure);
            app.SubjectInfoPanel.Title = 'SubjectInfo';
            app.NameEditFieldLabel = uilabel(app.SubjectInfoPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.LeftMapEditFieldLabel = uilabel(app.SubjectInfoPanel);
            app.LeftMapEditFieldLabel.Text = 'LeftMap';
            app.LeftMapEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.RightMapEditFieldLabel = uilabel(app.SubjectInfoPanel);
            app.RightMapEditFieldLabel.Text = 'RightMap';
            app.RightMapEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.fltpHzButtonGroup = uibuttongroup(app.UIFigure);
            app.fltpHzButtonGroup.Title = 'fltp(Hz)';
            app.Button = uiradiobutton(app.fltpHzButtonGroup);
            app.Button.Text = '100';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.fltpHzButtonGroup);
            app.Button_2.Text = '200';
            app.Button_3 = uiradiobutton(app.fltpHzButtonGroup);
            app.Button_3.Text = '300';
            app.otherButton = uiradiobutton(app.fltpHzButtonGroup);
            app.otherButton.Text = 'other';
            app.fltpHzEditField = uieditfield(app.fltpHzButtonGroup, 'numeric');
            app.fltpHzEditField.Limits = [0 500];
            app.fltpHzEditField.ValueChangedFcn = createCallbackFcn(app, @fltpHzEditFieldValueChanged, true);
            app.fltpHzEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end